classdef applicazionebottone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SATURAZIONEO2EditField         matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        StoricoTable                   matlab.ui.control.Table
        AVVIAButton                    matlab.ui.control.Button
        CODICELamp                     matlab.ui.control.Lamp
        CODICELampLabel                matlab.ui.control.Label
        IndicazionidelsistemaTextArea  matlab.ui.control.TextArea
        TextArea2Label                 matlab.ui.control.Label
        SintomienotemedicheTextArea    matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        PAmmHgEditField                matlab.ui.control.NumericEditField
        PAmmHgEditFieldLabel           matlab.ui.control.Label
        FCbpmEditField                 matlab.ui.control.NumericEditField
        FCbpmEditFieldLabel            matlab.ui.control.Label
        ETEditField                    matlab.ui.control.NumericEditField
        ETEditFieldLabel               matlab.ui.control.Label
        NOMEECOGNOMEEditField          matlab.ui.control.EditField
        NOMEECOGNOMEEditFieldLabel     matlab.ui.control.Label
        CODICEFISCALEEditField         matlab.ui.control.EditField
        CODICEFISCALEEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AVVIAButton
        function AVVIAButtonPushed(app, event)
% Code placement: Sostituisci tutto il corpo della callback del tuo bottone AVVIA

%% 1. Recupero dati dall'interfaccia grafica
cf_paziente      = upper(strip(char(app.CODICEFISCALEEditField.Value))); 
nome_cognome     = char(app.NOMEECOGNOMEEditField.Value);
sat              = app.SATURAZIONEO2EditField.Value; 
fc               = app.FCbpmEditField.Value;
pa               = app.PAmmHgEditField.Value;    
eta              = app.ETEditField.Value; 
sintomi_correnti = char(app.SintomienotemedicheTextArea.Value); 

if isempty(cf_paziente) || length(cf_paziente) < 16
    app.IndicazionidelsistemaTextArea.Value = "ERRORE: Inserire un Codice Fiscale valido di 16 caratteri.";
    return;
end

db_file = 'database_ospedale.mat';
if ~exist(db_file, 'file')
    creaDatabase();
end

% Carichiamo le tabelle dal database relazionale .mat
load(db_file, 'PAZIENTE', 'TRIAGE');

%% 2. Gestione Archivio Pazienti (Ricerca / Inserimento)
% NOTA: Manteniamo allergieDB vuota per passarla a valutaTriage senza rompere la funzione
allergieDB = ''; 
storicoDB = '';

riga_paziente = strcmp(PAZIENTE.CodiceFiscale, cf_paziente);

if any(riga_paziente)
    % Il paziente esiste già nel database, recuperiamo solo lo storico clinico
    if iscell(PAZIENTE.StoricoClinico)
        storicoDB = char(PAZIENTE.StoricoClinico{riga_paziente});
    else
        storicoDB = char(PAZIENTE.StoricoClinico(riga_paziente));
    end
else
    % Se l'utente non ha scritto nulla, allora diventa Anonimo
    if isempty(strip(nome_cognome))
        nome_cognome = 'Paziente Anonimo';
    end
    
    % MODIFICATO: Rimossa la colonna 'Allergie' e il relativo valore vuoto {''}
    nuovo_paziente = table({cf_paziente}, {nome_cognome}, eta, {''}, ...
        'VariableNames', {'CodiceFiscale', 'NomeCognome', 'Eta', 'StoricoClinico'});
    PAZIENTE = [PAZIENTE; nuovo_paziente];
    save(db_file, 'PAZIENTE', 'TRIAGE');
end

%% 3. Calcolo Triage Clinico
[codiceColore, indicazioni] = valutaTriage(sat, fc, pa, sintomi_correnti, allergieDB, storicoDB);

%% 4. Calcolo Logistica Ospedaliera (Assegnazione Reparto e Tempo d'Attesa)
reparti.Cardiologia = struct('MediciInTurno', 2, 'StanzeTotali', 3,  'StanzeOccupate', randi([0, 3]));
reparti.Medicina    = struct('MediciInTurno', 4, 'StanzeTotali', 10, 'StanzeOccupate', randi([5, 10]));
reparti.Pediatria   = struct('MediciInTurno', 2, 'StanzeTotali', 4,  'StanzeOccupate', randi([1, 4]));

sintomiLower = lower(sintomi_correnti);

% Controllo Pediatria basato su Età (< 14 anni) o parole chiave nei sintomi
if eta < 14 || contains(sintomiLower, 'bambino') || contains(sintomiLower, 'bambina')
    repartoAssegnato = 'Pediatria';
elseif contains(sintomiLower, 'toracico') || contains(sintomiLower, 'cuore') || contains(sintomiLower, 'arresto') || strcmpi(codiceColore, 'Rosso')
    repartoAssegnato = 'Cardiologia';
else
    repartoAssegnato = 'Medicina';
end

res = reparti.(repartoAssegnato);
saturazioneStanze = res.StanzeOccupate / res.StanzeTotali;

switch codiceColore
    case 'Rosso',  tempoBase = 0;
    case 'Giallo', tempoBase = 15;
    case 'Verde',  tempoBase = 60;
    otherwise,     tempoBase = 30;
end

if strcmpi(codiceColore, 'Rosso')
    minutiAttesa = 0;
else
    if res.StanzeOccupate >= res.StanzeTotali
        moltiplicatoreCoda = 2.5; 
    else
        moltiplicatoreCoda = 1 + (saturazioneStanze * 1.5);
    end
    minutiAttesa = round(((tempoBase * moltiplicatoreCoda) / res.MediciInTurno) + randi([-5, 5]));
    if minutiAttesa < 2, minutiAttesa = 2; end
end

% Output grafico pulito e formattato come richiesto
indicazioni_complete = sprintf("%s\n\nSintomi e note mediche: %s\n\n[LOGISTICA PRONTO SOCCORSO]\n-> Reparto Assegnato: %s\n-> Tempo di attesa stimato: %d minuti.", ...
    indicazioni, sintomi_correnti, repartoAssegnato, minutiAttesa);
app.IndicazionidelsistemaTextArea.Value = indicazioni_complete;

%% 5. Aggiornamento Storico Visivo nella UI Table
data_ora_corrente = char(datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'));
dati_tabella = {};
filtro_triage = strcmp(TRIAGE.FK_Paziente, cf_paziente);
if any(filtro_triage)
    cronologia_paziente = TRIAGE(filtro_triage, :);
    cronologia_paziente = sortrows(cronologia_paziente, 'DataOra', 'descend');
    
    for i = 1:height(cronologia_paziente)
        d_ora = cronologia_paziente.DataOra{i};
        c_cod = cronologia_paziente.CodiceAssegnato{i};
        s_sint = cronologia_paziente.Sintomi{i};
        dati_tabella = [dati_tabella; {char(d_ora), char(c_cod), char(s_sint)}];
    end
end
app.StoricoTable.Data = dati_tabella;

%% 6. Lampadina e Allarme Acustico
switch codiceColore
    case 'Rosso'
        app.CODICELamp.Color = [1 0 0];   
        fs = 8000; t = 0:1/fs:0.25;
        suono = [sin(2*pi*880*t), sin(2*pi*440*t), sin(2*pi*880*t)];
        sound(suono, fs);
    case 'Giallo'
        app.CODICELamp.Color = [1 0.8 0]; 
    case 'Verde'
        app.CODICELamp.Color = [0 1 0];   
    otherwise
        app.CODICELamp.Color = [0.5 0.5 0.5]; 
end

%% 7. Salvataggio del Triage nel Database .mat
nuovo_triage = table({cf_paziente}, fc, pa, sat, {sintomi_correnti}, {codiceColore}, {data_ora_corrente}, {repartoAssegnato}, minutiAttesa, ...
    'VariableNames', {'FK_Paziente', 'FrequenzaCardiaca', 'PressioneSistolica', 'SaturazioneOssigeno', 'Sintomi', 'CodiceAssegnato', 'DataOra', 'RepartoAssegnato', 'MinutiAttesa'});
TRIAGE = [TRIAGE; nuovo_triage];
save(db_file, 'PAZIENTE', 'TRIAGE');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CODICEFISCALEEditFieldLabel = uilabel(app.UIFigure);
            app.CODICEFISCALEEditFieldLabel.Text = 'CODICE FISCALE ';
            app.CODICEFISCALEEditField = uieditfield(app.UIFigure, 'text');
            app.NOMEECOGNOMEEditFieldLabel = uilabel(app.UIFigure);
            app.NOMEECOGNOMEEditFieldLabel.Text = 'NOME E COGNOME';
            app.NOMEECOGNOMEEditField = uieditfield(app.UIFigure, 'text');
            app.ETEditFieldLabel = uilabel(app.UIFigure);
            app.ETEditFieldLabel.Text = 'ETÀ';
            app.ETEditField = uieditfield(app.UIFigure, 'numeric');
            app.FCbpmEditFieldLabel = uilabel(app.UIFigure);
            app.FCbpmEditFieldLabel.Text = 'FC (bpm)';
            app.FCbpmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PAmmHgEditFieldLabel = uilabel(app.UIFigure);
            app.PAmmHgEditFieldLabel.Text = 'PA (mmHg)';
            app.PAmmHgEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Sintomi e note mediche';
            app.SintomienotemedicheTextArea = uitextarea(app.UIFigure);
            app.TextArea2Label = uilabel(app.UIFigure);
            app.TextArea2Label.Text = 'Indicazioni del sistema';
            app.IndicazionidelsistemaTextArea = uitextarea(app.UIFigure);
            app.CODICELampLabel = uilabel(app.UIFigure);
            app.CODICELampLabel.Text = 'CODICE';
            app.CODICELamp = uilamp(app.UIFigure);
            app.AVVIAButton = uibutton(app.UIFigure, 'push');
            app.AVVIAButton.ButtonPushedFcn = createCallbackFcn(app, @AVVIAButtonPushed, true);
            app.AVVIAButton.Text = 'AVVIA';
            app.StoricoTable = uitable(app.UIFigure);
            app.StoricoTable.ColumnName = {'Data e ora'; 'Codice'; 'Sintomi Passati'; ''};
            app.StoricoTable.RowName = {};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'SATURAZIONE O2';
            app.SATURAZIONEO2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicazionebottone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end